classdef triple_integral < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        yCheckBox_2             matlab.ui.control.CheckBox
        xCheckBox_2             matlab.ui.control.CheckBox
        zCheckBox_2             matlab.ui.control.CheckBox
        xCheckBox               matlab.ui.control.CheckBox
        zCheckBox               matlab.ui.control.CheckBox
        yCheckBox               matlab.ui.control.CheckBox
        ComputeButton           matlab.ui.control.StateButton
        ResultEditField         matlab.ui.control.EditField
        ResultEditFieldLabel    matlab.ui.control.Label
        zmaxEditField           matlab.ui.control.EditField
        zmaxEditFieldLabel      matlab.ui.control.Label
        zminEditField           matlab.ui.control.EditField
        zminEditFieldLabel      matlab.ui.control.Label
        ymaxEditField           matlab.ui.control.EditField
        ymaxEditFieldLabel      matlab.ui.control.Label
        yminEditField           matlab.ui.control.EditField
        yminEditFieldLabel      matlab.ui.control.Label
        xmaxEditField           matlab.ui.control.EditField
        xmaxEditFieldLabel      matlab.ui.control.Label
        xminEditField           matlab.ui.control.EditField
        xminEditFieldLabel      matlab.ui.control.Label
        FunctionEditField       matlab.ui.control.EditField
        FunctionEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ComputeButton
        function ComputeButtonValueChanged(app, event)

            
            %--------Function value----------
            f= app.FunctionEditField.Value;
            xmin= app.xminEditField.Value;
            xmax= app.xmaxEditField.Value;
            ymin= app.yminEditField.Value;
            ymax= app.ymaxEditField.Value;
            zmin= app.zminEditField.Value;
            zmax= app.zmaxEditField.Value;
            
            % Function variables of x
            if app.yCheckBox == 1
                xmin = "@(y)"+xmin;
                xmax = "@(y)"+xmax;
            end
            if app.zCheckBox == 1
                xmin = "@(z)"+xmin;
                xmax = "@(z)"+xmax;
            end

            % Function variables of y
            if app.xCheckBox == 1
                ymin = "@(x)"+ymin;
                ymax = "@(x)"+ymax;
            end
            if app.zCheckBox_2 == 1
                ymin = "@(z)"+ymin;
                ymax = "@(z)"+ymax;
            end

            % Function variables of z
            if app.xCheckBox_2 == 1
                zmin = "@(x)"+zmin;
                zmax = "@(x)"+zmax;
            end
            if app.yCheckBox_2 == 1
                zmin = "@(y)"+zmin;
                zmax = "@(y)"+zmax;
            end
            

            %---------Change format----------- 
            f="@(x,y,z)" + f 
            f=str2func(f);

            %Change format of x
            if isnan(str2double(xmin)) 
                xmin=str2func(xmin);
            else
                xmin=str2double(xmin);
            end
            if isnan(str2double(xmax)) 
                xmax=str2func(xmax);
            else
                xmax=str2double(xmax);
            end
            
            %Change format of y
            if isnan(str2double(ymin))
                ymin=str2func(ymin);
            else
                ymin=str2double(ymin);
            end
            if isnan(str2double(ymax))
                ymax=str2func(ymax);
            else
                ymax=str2double(ymax);
            end

            %Change format of z
            if isnan(str2double(zmin))
                zmin=str2func(zmin);
            else
                zmin=str2double(zmin);
            end
            if isnan(str2double(zmax)) 
                zmax=str2func(zmax);
            else
                zmax=str2double(zmax);
            end
            
            %---------Calculus------------
            
            r=integral3(f,xmin,xmax,ymin,ymax,zmin,zmax);
            r=string(r)

            app.ResultEditField.Value = r;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FunctionEditFieldLabel = uilabel(app.UIFigure);
            app.FunctionEditFieldLabel.Text = 'Function';
            app.FunctionEditField = uieditfield(app.UIFigure, 'text');
            app.xminEditFieldLabel = uilabel(app.UIFigure);
            app.xminEditFieldLabel.Text = 'x min';
            app.xminEditField = uieditfield(app.UIFigure, 'text');
            app.xmaxEditFieldLabel = uilabel(app.UIFigure);
            app.xmaxEditFieldLabel.Text = 'x max';
            app.xmaxEditField = uieditfield(app.UIFigure, 'text');
            app.yminEditFieldLabel = uilabel(app.UIFigure);
            app.yminEditFieldLabel.Text = 'y min';
            app.yminEditField = uieditfield(app.UIFigure, 'text');
            app.ymaxEditFieldLabel = uilabel(app.UIFigure);
            app.ymaxEditFieldLabel.Text = 'y max';
            app.ymaxEditField = uieditfield(app.UIFigure, 'text');
            app.zminEditFieldLabel = uilabel(app.UIFigure);
            app.zminEditFieldLabel.Text = 'z min';
            app.zminEditField = uieditfield(app.UIFigure, 'text');
            app.zmaxEditFieldLabel = uilabel(app.UIFigure);
            app.zmaxEditFieldLabel.Text = 'z max';
            app.zmaxEditField = uieditfield(app.UIFigure, 'text');
            app.ResultEditFieldLabel = uilabel(app.UIFigure);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.UIFigure, 'text');
            app.ComputeButton = uibutton(app.UIFigure, 'state');
            app.ComputeButton.ValueChangedFcn = createCallbackFcn(app, @ComputeButtonValueChanged, true);
            app.ComputeButton.Text = 'Compute';
            app.yCheckBox = uicheckbox(app.UIFigure);
            app.yCheckBox.Text = 'y';
            app.zCheckBox = uicheckbox(app.UIFigure);
            app.zCheckBox.Text = 'z';
            app.xCheckBox = uicheckbox(app.UIFigure);
            app.xCheckBox.Text = 'x';
            app.zCheckBox_2 = uicheckbox(app.UIFigure);
            app.zCheckBox_2.Text = 'z';
            app.xCheckBox_2 = uicheckbox(app.UIFigure);
            app.xCheckBox_2.Text = 'x';
            app.yCheckBox_2 = uicheckbox(app.UIFigure);
            app.yCheckBox_2.Text = 'y';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = triple_integral

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end